classdef main < matlab.apps.AppBase

        % Properties that correspond to app components
        properties (Access = public)
                UIFigure                  matlab.ui.Figure
                Label_2                   matlab.ui.control.Label
                RegisterYourselvesPanel   matlab.ui.container.Panel
                EnterYourNameEditField    matlab.ui.control.EditField
                EnterYourNameLabel        matlab.ui.control.Label
                StartButton               matlab.ui.control.Button
                Label                     matlab.ui.control.Label
                IdentificationModeButton  matlab.ui.control.StateButton
                VerificationModeButton    matlab.ui.control.Button
                RegisterYourselvesButton  matlab.ui.control.Button
                FaceRecognitionAppLabel   matlab.ui.control.Label
        end


        properties (Access = public)
                trainingFeatures        % trainingFeatures are evaluated at
                                        % startup over the collected database
                trainingLabel           % label of associated person
        end


        % Callbacks that handle component events
        methods (Access = private)

                % Code that executes after component creation
                function startupFcn(app)
                        [res] = ttSystem(app);
                        if (res < 0)
                                msgbox(['An error occured while Training and Testing the System,' ...
                                        ' try running the app again']);
                                return;
                        end
                end

                % Callback function: EnterYourNameEditField, RegisterYourselvesButton
                function RegisterYourselvesButtonPushed(app, event)
                        registerClient(app);
            
         
                end

                % Close request function: UIFigure
                function UIFigureCloseRequest(app, event)
                        delete(app)
                        
                end

                % Callback function: RegisterYourselvesPanel, StartButton
                function StartButtonPushed(app, event)
                        defaultDB = 'database';
                        currentDir = pwd();
                        dbPath = fullfile(currentDir, defaultDB);
                        value = app.EnterYourNameEditField.Value;
                        takeSnapshots(value, dbPath, 10, app);
                end

                % Button pushed function: VerificationModeButton
                function VerificationModeButtonPushed(app, event)
                        [res] = identifyClient(app);
                        if res < 0
                                msgbox(['Cannot Identify yourselfes. Please try again']);
                        end
                end
        end

        % Component initialization
        methods (Access = private)

                % Create UIFigure and components
                function createComponents(app)
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                        app.UIFigure = uifigure('Visible', 'off');
                        app.UIFigure.Name = 'MATLAB App';
                        app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
                        app.FaceRecognitionAppLabel = uilabel(app.UIFigure);
                        app.FaceRecognitionAppLabel.Text = 'Face Recognition App';
                        app.RegisterYourselvesButton = uibutton(app.UIFigure, 'push');
                        app.RegisterYourselvesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterYourselvesButtonPushed, true);
                        app.RegisterYourselvesButton.Text = 'Register Yourselves';
                        app.VerificationModeButton = uibutton(app.UIFigure, 'push');
                        app.VerificationModeButton.ButtonPushedFcn = createCallbackFcn(app, @VerificationModeButtonPushed, true);
                        app.VerificationModeButton.Text = 'Verification Mode';
                        app.IdentificationModeButton = uibutton(app.UIFigure, 'state');
                        app.IdentificationModeButton.Text = 'Identification Mode';
                        app.RegisterYourselvesPanel = uipanel(app.UIFigure);
                        app.RegisterYourselvesPanel.Title = 'Register Yourselves';
                        app.RegisterYourselvesPanel.Visible = 'off';
                        app.RegisterYourselvesPanel.ButtonDownFcn = createCallbackFcn(app, @StartButtonPushed, true);
                        app.Label = uilabel(app.RegisterYourselvesPanel);
                        app.Label.Text = '';
                        app.StartButton = uibutton(app.RegisterYourselvesPanel, 'push');
                        app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
                        app.StartButton.Text = 'Start';
                        app.EnterYourNameLabel = uilabel(app.RegisterYourselvesPanel);
                        app.EnterYourNameLabel.Text = 'Enter Your Name:';
                        app.EnterYourNameEditField = uieditfield(app.RegisterYourselvesPanel, 'text');
                        app.EnterYourNameEditField.ValueChangedFcn = createCallbackFcn(app, @RegisterYourselvesButtonPushed, true);
                        app.Label_2 = uilabel(app.UIFigure);
                        app.Label_2.Text = '';
                        % Show the figure after all components are created
                        app.UIFigure.Visible = 'on';
                end
        end

        % App creation and deletion
        methods (Access = public)

                % Construct app
                function app = main

                        % Create UIFigure and components
                        createComponents(app)

                        % Register the app with App Designer
                        registerApp(app, app.UIFigure)

                        % Execute the startup function
                        runStartupFcn(app, @startupFcn)

                        if nargout == 0
                                clear app
                        end
                end

                % Code that executes before app deletion
                function delete(app)

                        % Delete UIFigure when app is deleted
                        delete(app.UIFigure)
                end
        end
end